classdef MusicNotepad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditButton_Track2               matlab.ui.control.Button
        EchoCheckBox_Track2             matlab.ui.control.CheckBox
        ReverbCheckBox_Track2           matlab.ui.control.CheckBox
        EditButton_Track1               matlab.ui.control.Button
        EchoCheckBox_Track1             matlab.ui.control.CheckBox
        ReverbCheckBox_Track1           matlab.ui.control.CheckBox
        LampLabel_Track2                matlab.ui.control.Label
        LampLabel_Track1                matlab.ui.control.Label
        EmptytracksLabel                matlab.ui.control.Label
        Panel_Track2                    matlab.ui.container.Panel
        Panel_Track1                    matlab.ui.container.Panel
        StopButton_Track2               matlab.ui.control.Button
        StopButton_Track1               matlab.ui.control.Button
        ConverttoMIDIButton_Track1      matlab.ui.control.Button
        ConverttoMIDIButton_Track2      matlab.ui.control.Button
        CleanButton_Track2              matlab.ui.control.Button
        CleanButton_Track1              matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        MUSICNOTEPADLabel               matlab.ui.control.Label
        ChoosesynthesizerDropDown_Track2  matlab.ui.control.DropDown
        ChoosesynthesizerDropDownLabel  matlab.ui.control.Label
        ChoosesynthesizerDropDown_Track1  matlab.ui.control.DropDown
        ChoosesynthesizerDropDown_2Label  matlab.ui.control.Label
        Lamp_Track2                     matlab.ui.control.Lamp
        Lamp_Track1                     matlab.ui.control.Lamp
        VolumeKnob_Track1               matlab.ui.control.Knob
        VolumeKnob_2Label               matlab.ui.control.Label
        VolumeKnob_Track2               matlab.ui.control.Knob
        VolumeKnobLabel                 matlab.ui.control.Label
        RecordButton_Track1             matlab.ui.control.Button
        RecordButton_Track2             matlab.ui.control.Button
    end

    
    properties (Access = private)
        audioRecorder 
        audioPlayer
        midiHandler
        synthesizer
        axesTrack1 
        axesTrack2 
        isMidiTrack1 = false;
        isMidiTrack2 = false;
        effectsOptionsAppTrack1;
        effectsOptionsAppTrack2;
        delayTrack1;
        delayTrack2;
        reverbTrack1;
        reverbTrack2;
    end
    
    methods (Access = private)
        
        function axesSetup(app)
            app.axesTrack1 = axes(app.Panel_Track1);
            app.axesTrack2 = axes(app.Panel_Track2);
            linkaxes([app.axesTrack1, app.axesTrack2],'x');
            xlim(app.axesTrack1, [0 1e-6]);
        end
        
        function toggleComponents(app, enableFlag)

            components = [app.PlayButton, app.StopButton, app.RecordButton_Track1, ...
                          app.RecordButton_Track2, app.ConverttoMIDIButton_Track1, ...
                          app.ConverttoMIDIButton_Track2, app.EditButton_Track1, app.EditButton_Track2, ...
                          app.CleanButton_Track1, app.CleanButton_Track2, ...
                          app.EchoCheckBox_Track1, app.EchoCheckBox_Track2, ...
                          app.ReverbCheckBox_Track1, app.ReverbCheckBox_Track2, ...
                          app.ChoosesynthesizerDropDown_Track1, app.ChoosesynthesizerDropDown_Track2, ...
                          app.VolumeKnob_Track1, app.VolumeKnob_Track2];
            if enableFlag
                flag = 'On';
            else
                flag = 'Off';
            end 
                set(components, 'Enable', flag)
            end
        
        function performEffectTrack1(app, effect)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Performing effects', ...
                                'Indeterminate','on');
            switch (effect)
                case "echo"
                app.audioPlayer.Track1Audio = app.delayTrack1.doDelay(app.audioPlayer.Track1Audio);
                case "reverb"
                app.audioPlayer.Track1Audio = app.reverbTrack1.doReverb(app.audioPlayer.Track1Audio);   
            end
            close(d);
        end
         function performEffectTrack2(app, effect)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Performing effects', ...
                                'Indeterminate','on');
            switch (effect)
                case "echo"
                app.audioPlayer.Track2Audio = app.delayTrack2.doDelay(app.audioPlayer.Track2Audio);
                case "reverb"
                app.audioPlayer.Track2Audio = app.reverbTrack2.doReverb(app.audioPlayer.Track2Audio);   
            end
            close(d);
        end
        
        function effectDisabledTrack1(app)
            if app.isMidiTrack1
                app.audioPlayer.Track1Audio = app.synthesizer.createAudio(app.midiHandler.track1Midi);
            else
                app.audioPlayer.Track1Audio = app.audioRecorder.rawTrack1Audio;
            end
        end
        function effectDisabledTrack2(app)
            if app.isMidiTrack2
                app.audioPlayer.Track2Audio = app.synthesizer.createAudio(app.midiHandler.track2Midi);
            else
                app.audioPlayer.Track2Audio = app.audioRecorder.rawTrack2Audio;
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateEffectsTrack1(app, delay, reverb)
            app.delayTrack1 = delay;
            app.reverbTrack1 = reverb;
            if app.EchoCheckBox_Track1.Value == 1
                app.performEffectTrack1("echo");
            elseif app.ReverbCheckBox_Track1.Value == 1
                app.performEffectTrack1("reverb");
            end
            app.EditButton_Track1.Enable = 'on';
        end
        function updateEffectsTrack2(app, delay, reverb)
            app.delayTrack2 = delay;
            app.reverbTrack2 = reverb;
            if app.EchoCheckBox_Track2.Value == 1
                app.performEffectTrack2("echo");
            elseif app.ReverbCheckBox_Track2.Value == 1
                app.performEffectTrack2("reverb");
            end
            app.EditButton_Track2.Enable = 'on';
        end
        
        function unlockEditButtons(app)
            app.EditButton_Track1.Enable = 'on';
            app.EditButton_Track2.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MainWindowStartupFcn(app)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Initalizing', ...
                                'Indeterminate','on');
            app.audioRecorder = AudioRecorder();
            app.audioPlayer = AudioPlayer();
            app.midiHandler = MidiHandler();
            app.synthesizer = Synthesizer();
            app.axesSetup();
            app.delayTrack1 = Delay();
            app.delayTrack2 = Delay();
            app.reverbTrack1 = Reverb();
            app.reverbTrack2 = Reverb();
            close(d);
        end

        % Button pushed function: RecordButton_Track1
        function RecordButton_Track1Pushed(app, event)
            app.toggleComponents(false);
            app.EmptytracksLabel.Visible = 0;  
            app.Lamp_Track1.Color = [1, 0, 0];
            app.RecordButton_Track1.Visible = 0;
            app.EchoCheckBox_Track1.Value = 0;
            app.ReverbCheckBox_Track1.Value = 0;
            app.StopButton_Track1.Visible = 1;
            try
                app.audioRecorder.record(app.axesTrack1, 1);
            catch
               disp("Recording error")
               return; 
            end
            app.audioPlayer.Track1Audio = app.audioRecorder.rawTrack1Audio;
            app.Lamp_Track1.Color = [0, 1, 0];
            app.CleanButton_Track1.Visible = 1;
            app.ConverttoMIDIButton_Track1.Visible = 1;
            app.toggleComponents(true);
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: RecordButton_Track2
        function RecordButton_Track2Pushed(app, event)
            app.toggleComponents(false);
            app.EmptytracksLabel.Visible = 0;           
            app.Lamp_Track2.Color = [1, 0, 0];
            app.RecordButton_Track2.Visible = 0;
            app.EchoCheckBox_Track2.Value = 0;
            app.ReverbCheckBox_Track2.Value = 0;
            app.StopButton_Track2.Visible = 1;
            try
                app.audioRecorder.record(app.axesTrack2, 2);
            catch
                disp("Recording error")
                return; 
            end
            app.audioPlayer.Track2Audio = app.audioRecorder.rawTrack2Audio;
            app.Lamp_Track2.Color = [0, 1, 0];
            app.CleanButton_Track2.Visible = 1;
            app.ConverttoMIDIButton_Track2.Visible = 1;
            app.toggleComponents(true);
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: StopButton_Track1
        function StopButton_Track1Pushed(app, event)
            app.audioRecorder.stopRecording();
            app.StopButton_Track1.Visible = 0;
        end

        % Button pushed function: StopButton_Track2
        function StopButton_Track2Pushed(app, event)
            app.audioRecorder.stopRecording();
            app.StopButton_Track2.Visible = 'off';
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if ~app.audioPlayer.isEmpty()
                app.toggleComponents(false);
                app.StopButton.Enable = 'on';
                try
                    app.audioPlayer.play(app.axesTrack1, app.axesTrack2);
                    app.toggleComponents(true);
                    app.StopButton.Enable = 'off';
                catch
                    disp("Audio player error")
                    return;
                end
            else
                app.EmptytracksLabel.Visible = 1;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.audioPlayer.stop();
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: ConverttoMIDIButton_Track1
        function ConverttoMIDIButton_Track1Pushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Converting to Midi', ...
                            'Indeterminate','on');
            app.EchoCheckBox_Track1.Value = 0;
            app.ReverbCheckBox_Track1.Value = 0;
            app.midiHandler.convertAudioToMidi(app.audioRecorder.rawTrack1Audio, 1);
            app.midiHandler.drawMidiPlot(app.axesTrack1, 1);
            value = app.ChoosesynthesizerDropDown_Track1.Value;
            app.synthesizer.currentSynth = value;
            app.audioPlayer.Track1Audio = app.synthesizer.createAudio(app.midiHandler.track1Midi);
            app.Lamp_Track1.Color = [0.4660 0.6740 0.1880];
            app.LampLabel_Track1.Text = 'MIDI';
            app.ConverttoMIDIButton_Track1.Visible = 0;
            app.isMidiTrack1 = true;
            close(d);
        end

        % Button pushed function: ConverttoMIDIButton_Track2
        function ConverttoMIDIButton_Track2Pushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Converting to Midi', ...
                            'Indeterminate','on');
            app.EchoCheckBox_Track2.Value = 0;
            app.ReverbCheckBox_Track2.Value = 0;
            app.midiHandler.convertAudioToMidi(app.audioRecorder.rawTrack2Audio, 2);
            tempAxes = app.axesTrack1;
            app.midiHandler.drawMidiPlot(app.axesTrack2, 2);
            app.axesTrack1 = tempAxes;
            value = app.ChoosesynthesizerDropDown_Track2.Value;
            app.synthesizer.currentSynth = value;
            app.audioPlayer.Track2Audio = app.synthesizer.createAudio(app.midiHandler.track2Midi);
            
            app.Lamp_Track2.Color = [0.4660 0.6740 0.1880];
            app.LampLabel_Track2.Text = 'MIDI';
            app.ConverttoMIDIButton_Track2.Visible = 0;
            app.isMidiTrack2 = true;
            close(d);
        end

        % Value changed function: VolumeKnob_Track1
        function VolumeKnob_Track1ValueChanged(app, event)
            value = app.VolumeKnob_Track1.Value;
            app.audioPlayer.Track1Volume = (value/6);
        end

        % Value changed function: VolumeKnob_Track2
        function VolumeKnob_Track2ValueChanged(app, event)
            value = app.VolumeKnob_Track2.Value;
            app.audioPlayer.Track2Volume = (value/6);
        end

        % Button pushed function: CleanButton_Track1
        function CleanButton_Track1Pushed(app, event)
            cla(app.axesTrack1)
            app.EchoCheckBox_Track1.Value = 0;
            app.ReverbCheckBox_Track1.Value = 0;
            app.audioPlayer.Track1Audio = [];
            app.audioRecorder.rawTrack1Audio = [];
            app.RecordButton_Track1.Visible = 1;
            app.CleanButton_Track1.Visible = 0;
            app.LampLabel_Track1.Text = "Recording";
            app.Lamp_Track1.Color = [0 1 0];
            app.isMidiTrack1 = false;
            app.ConverttoMIDIButton_Track1.Visible = 0;
            delete(app.effectsOptionsAppTrack1);
            if app.isMidiTrack2
                app.midiHandler.drawMidiPlot(app.axesTrack2, 2);
            else
                plot(app.axesTrack2, app.audioRecorder.rawTrack2Audio);
            end
        end

        % Button pushed function: CleanButton_Track2
        function CleanButton_Track2Pushed(app, event)
            cla(app.axesTrack2)
            app.EchoCheckBox_Track2.Value = 0;
            app.ReverbCheckBox_Track2.Value = 0;
            app.audioPlayer.Track2Audio = [];
            app.audioRecorder.rawTrack2Audio = [];
            app.RecordButton_Track2.Visible = 1;
            app.CleanButton_Track2.Visible = 0;
            app.LampLabel_Track2.Text = "Recording";
            app.Lamp_Track2.Color = [0 1 0];
            app.isMidiTrack2 = false;
            app.ConverttoMIDIButton_Track2.Visible = 0;
            delete(app.effectsOptionsAppTrack2);
            if app.isMidiTrack1
                app.midiHandler.drawMidiPlot(app.axesTrack1, 1);
            else
                plot(app.axesTrack1, app.audioRecorder.rawTrack1Audio);
            end
        end

        % Value changed function: ChoosesynthesizerDropDown_Track1
        function ChoosesynthesizerDropDown_Track1ValueChanged(app, event)
            value = app.ChoosesynthesizerDropDown_Track1.Value;
            app.synthesizer.currentSynth = value;
            if app.isMidiTrack1
                app.audioPlayer.Track1Audio = app.synthesizer.createAudio(app.midiHandler.track1Midi);
                app.EchoCheckBox_Track1.Value = 0;
                app.ReverbCheckBox_Track1.Value = 0;
            end
        end

        % Value changed function: ChoosesynthesizerDropDown_Track2
        function ChoosesynthesizerDropDown_Track2ValueChanged(app, event)
            value = app.ChoosesynthesizerDropDown_Track2.Value;
            app.synthesizer.currentSynth = value;
            if app.isMidiTrack2
                app.audioPlayer.Track2Audio = app.synthesizer.createAudio(app.midiHandler.track2Midi);
                app.EchoCheckBox_Track2.Value = 0;
                app.ReverbCheckBox_Track2.Value = 0;
            end
        end

        % Button pushed function: EditButton_Track1
        function EditButton_Track1Pushed(app, event)
           app.EditButton_Track1.Enable = 'off';
           app.effectsOptionsAppTrack1 = EffectsOptions(app, app.delayTrack1, app.reverbTrack1, 1);
        end

        % Button pushed function: EditButton_Track2
        function EditButton_Track2Pushed(app, event)
            app.EditButton_Track2.Enable = 'off';
            app.effectsOptionsAppTrack2 = EffectsOptions(app, app.delayTrack2, app.reverbTrack2, 2);
        end

        % Value changed function: EchoCheckBox_Track1
        function EchoCheckBox_Track1ValueChanged(app, event)
            value = app.EchoCheckBox_Track1.Value;
            if value == 1 
                if app.ReverbCheckBox_Track1.Value == 1
                    app.ReverbCheckBox_Track1.Value = 0;
                end
                app.performEffectTrack1("echo");
            else
                app.effectDisabledTrack1();
            end
        end

        % Value changed function: EchoCheckBox_Track2
        function EchoCheckBox_Track2ValueChanged(app, event)
            value = app.EchoCheckBox_Track2.Value;
            if value == 1 
                if app.ReverbCheckBox_Track2.Value == 1
                    app.ReverbCheckBox_Track2.Value = 0;
                end
                app.performEffectTrack2("echo");
            else
                app.audioPlayer.Track2Audio = app.audioRecorder.rawTrack2Audio;
            end
        end

        % Value changed function: ReverbCheckBox_Track2
        function ReverbCheckBox_Track2ValueChanged(app, event)
            value = app.ReverbCheckBox_Track2.Value;
            if value == 1 
                if app.EchoCheckBox_Track2.Value == 1
                    app.EchoCheckBox_Track2.Value = 0;
                end
                app.performEffectTrack2("reverb");
            else
                app.audioPlayer.Track2Audio = app.audioRecorder.rawTrack2Audio;
            end
        end

        % Value changed function: ReverbCheckBox_Track1
        function ReverbCheckBox_Track1ValueChanged(app, event)
            value = app.ReverbCheckBox_Track1.Value;
            if value == 1 
                if app.EchoCheckBox_Track1.Value == 1
                    app.EchoCheckBox_Track1.Value = 0;
                end
                app.performEffectTrack1("reverb");
            else
                app.effectDisabledTrack1();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.effectsOptionsAppTrack1);
            delete(app.effectsOptionsAppTrack2);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RecordButton_Track2 = uibutton(app.UIFigure, 'push');
            app.RecordButton_Track2.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_Track2Pushed, true);
            app.RecordButton_Track2.Text = 'Record';
            app.RecordButton_Track1 = uibutton(app.UIFigure, 'push');
            app.RecordButton_Track1.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_Track1Pushed, true);
            app.RecordButton_Track1.Text = 'Record';
            app.VolumeKnobLabel = uilabel(app.UIFigure);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob_Track2 = uiknob(app.UIFigure, 'continuous');
            app.VolumeKnob_Track2.Limits = [1 12];
            app.VolumeKnob_Track2.MajorTicks = [1 6 12];
            app.VolumeKnob_Track2.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_Track2ValueChanged, true);
            app.VolumeKnob_Track2.Value = 6;
            app.VolumeKnob_2Label = uilabel(app.UIFigure);
            app.VolumeKnob_2Label.Text = 'Volume';
            app.VolumeKnob_Track1 = uiknob(app.UIFigure, 'continuous');
            app.VolumeKnob_Track1.Limits = [1 12];
            app.VolumeKnob_Track1.MajorTicks = [1 6 12];
            app.VolumeKnob_Track1.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_Track1ValueChanged, true);
            app.VolumeKnob_Track1.Value = 6;
            app.Lamp_Track1 = uilamp(app.UIFigure);
            app.Lamp_Track2 = uilamp(app.UIFigure);
            app.ChoosesynthesizerDropDown_2Label = uilabel(app.UIFigure);
            app.ChoosesynthesizerDropDown_2Label.Text = 'Choose synthesizer';
            app.ChoosesynthesizerDropDown_Track1 = uidropdown(app.UIFigure);
            app.ChoosesynthesizerDropDown_Track1.Items = {'Sine', 'Square', 'Sawtooth'};
            app.ChoosesynthesizerDropDown_Track1.ValueChangedFcn = createCallbackFcn(app, @ChoosesynthesizerDropDown_Track1ValueChanged, true);
            app.ChoosesynthesizerDropDown_Track1.Value = 'Sine';
            app.ChoosesynthesizerDropDownLabel = uilabel(app.UIFigure);
            app.ChoosesynthesizerDropDownLabel.Text = 'Choose synthesizer';
            app.ChoosesynthesizerDropDown_Track2 = uidropdown(app.UIFigure);
            app.ChoosesynthesizerDropDown_Track2.Items = {'Sine', 'Square', 'Sawtooth'};
            app.ChoosesynthesizerDropDown_Track2.ValueChangedFcn = createCallbackFcn(app, @ChoosesynthesizerDropDown_Track2ValueChanged, true);
            app.ChoosesynthesizerDropDown_Track2.Value = 'Sine';
            app.MUSICNOTEPADLabel = uilabel(app.UIFigure);
            app.MUSICNOTEPADLabel.Text = 'MUSIC NOTEPAD';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.CleanButton_Track1 = uibutton(app.UIFigure, 'push');
            app.CleanButton_Track1.ButtonPushedFcn = createCallbackFcn(app, @CleanButton_Track1Pushed, true);
            app.CleanButton_Track1.Visible = 'off';
            app.CleanButton_Track1.Text = 'Clear track';
            app.CleanButton_Track2 = uibutton(app.UIFigure, 'push');
            app.CleanButton_Track2.ButtonPushedFcn = createCallbackFcn(app, @CleanButton_Track2Pushed, true);
            app.CleanButton_Track2.Visible = 'off';
            app.CleanButton_Track2.Text = 'Clear track';
            app.ConverttoMIDIButton_Track2 = uibutton(app.UIFigure, 'push');
            app.ConverttoMIDIButton_Track2.ButtonPushedFcn = createCallbackFcn(app, @ConverttoMIDIButton_Track2Pushed, true);
            app.ConverttoMIDIButton_Track2.Visible = 'off';
            app.ConverttoMIDIButton_Track2.Text = 'Convert to MIDI';
            app.ConverttoMIDIButton_Track1 = uibutton(app.UIFigure, 'push');
            app.ConverttoMIDIButton_Track1.ButtonPushedFcn = createCallbackFcn(app, @ConverttoMIDIButton_Track1Pushed, true);
            app.ConverttoMIDIButton_Track1.Visible = 'off';
            app.ConverttoMIDIButton_Track1.Text = 'Convert to MIDI';
            app.StopButton_Track1 = uibutton(app.UIFigure, 'push');
            app.StopButton_Track1.ButtonPushedFcn = createCallbackFcn(app, @StopButton_Track1Pushed, true);
            app.StopButton_Track1.Visible = 'off';
            app.StopButton_Track1.Text = 'Stop';
            app.StopButton_Track2 = uibutton(app.UIFigure, 'push');
            app.StopButton_Track2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_Track2Pushed, true);
            app.StopButton_Track2.Visible = 'off';
            app.StopButton_Track2.Text = 'Stop';
            app.Panel_Track1 = uipanel(app.UIFigure);
            app.Panel_Track2 = uipanel(app.UIFigure);
            app.EmptytracksLabel = uilabel(app.UIFigure);
            app.EmptytracksLabel.Visible = 'off';
            app.EmptytracksLabel.Text = 'Empty tracks!';
            app.LampLabel_Track1 = uilabel(app.UIFigure);
            app.LampLabel_Track1.Text = 'Recording';
            app.LampLabel_Track2 = uilabel(app.UIFigure);
            app.LampLabel_Track2.Text = 'Recording';
            app.ReverbCheckBox_Track1 = uicheckbox(app.UIFigure);
            app.ReverbCheckBox_Track1.ValueChangedFcn = createCallbackFcn(app, @ReverbCheckBox_Track1ValueChanged, true);
            app.ReverbCheckBox_Track1.Text = 'Reverb';
            app.EchoCheckBox_Track1 = uicheckbox(app.UIFigure);
            app.EchoCheckBox_Track1.ValueChangedFcn = createCallbackFcn(app, @EchoCheckBox_Track1ValueChanged, true);
            app.EchoCheckBox_Track1.Text = 'Echo';
            app.EditButton_Track1 = uibutton(app.UIFigure, 'push');
            app.EditButton_Track1.ButtonPushedFcn = createCallbackFcn(app, @EditButton_Track1Pushed, true);
            app.EditButton_Track1.Text = 'Edit';
            app.ReverbCheckBox_Track2 = uicheckbox(app.UIFigure);
            app.ReverbCheckBox_Track2.ValueChangedFcn = createCallbackFcn(app, @ReverbCheckBox_Track2ValueChanged, true);
            app.ReverbCheckBox_Track2.Text = 'Reverb';
            app.EchoCheckBox_Track2 = uicheckbox(app.UIFigure);
            app.EchoCheckBox_Track2.ValueChangedFcn = createCallbackFcn(app, @EchoCheckBox_Track2ValueChanged, true);
            app.EchoCheckBox_Track2.Text = 'Echo';
            app.EditButton_Track2 = uibutton(app.UIFigure, 'push');
            app.EditButton_Track2.ButtonPushedFcn = createCallbackFcn(app, @EditButton_Track2Pushed, true);
            app.EditButton_Track2.Text = 'Edit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MusicNotepad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @MainWindowStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end