classdef projekt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EmptytracksLabel               matlab.ui.control.Label
        Panel_Track2                   matlab.ui.container.Panel
        Panel_Track1                   matlab.ui.container.Panel
        StopButton_Track2              matlab.ui.control.Button
        StopButton_Track1              matlab.ui.control.Button
        ConverttoMIDIButton_Track1     matlab.ui.control.Button
        ConverttoMIDIButton_Track2     matlab.ui.control.Button
        CleanButton_Track2             matlab.ui.control.Button
        CleanButton_Track1             matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        MUSICNOTEPADLabel              matlab.ui.control.Label
        ChooseinstrumentDropDown_2     matlab.ui.control.DropDown
        ChooseinstrumentDropDown_2Label  matlab.ui.control.Label
        ChooseinstrumentDropDown       matlab.ui.control.DropDown
        ChooseinstrumentDropDownLabel  matlab.ui.control.Label
        RecordingLamp_Track2           matlab.ui.control.Lamp
        RecordingLamp_2Label           matlab.ui.control.Label
        RecordingLamp_Track1           matlab.ui.control.Lamp
        RecordingLampLabel             matlab.ui.control.Label
        VolumeKnob_Track1              matlab.ui.control.Knob
        VolumeKnob_2Label              matlab.ui.control.Label
        VolumeKnob_Track2              matlab.ui.control.Knob
        VolumeKnobLabel                matlab.ui.control.Label
        RecordButton_Track1            matlab.ui.control.Button
        RecordButton_Track2            matlab.ui.control.Button
    end

    
    properties (Access = private)
        audioRecorder % Description
        audioPlayer
        midiHandler
        synthesizer
        Axes_Track1 % Description
        Axes_Track2 % Description
        isPlaying
        isRecording
    end
    
    methods (Access = private)
        
        function axesSetup(app)
            app.Axes_Track1 = axes(app.Panel_Track1);
            app.Axes_Track2 = axes(app.Panel_Track2);
            linkaxes([app.Axes_Track1, app.Axes_Track2],'x');
            xlim(app.Axes_Track1, [0 1e-6]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MainWindowStartupFcn(app)
            app.audioRecorder = AudioRecorder();
            app.audioPlayer = AudioPlayer();
            app.midiHandler = MidiHandler();
            app.synthesizer = Synthesizer();
            app.isPlaying = false;
            app.isRecording = false;
            app.axesSetup();
        end

        % Button pushed function: RecordButton_Track1
        function RecordButton_Track1Pushed(app, event)
            if ~app.isPlaying
                app.EmptytracksLabel.Visible = 0;  
                app.isRecording = true;
                app.RecordingLamp_Track1.Color = [1, 0, 0];
                app.RecordButton_Track1.Visible = 'off';
                app.StopButton_Track1.Visible = 'on';
                app.audioRecorder.record(app.Axes_Track1, 1);
                app.RecordingLamp_Track1.Color = [0, 1, 0];
                app.audioPlayer.updateTracks(app.audioRecorder.getTrack1Audio(), 1);
                app.isRecording = false;
            end
        end

        % Button pushed function: StopButton_Track1
        function StopButton_Track1Pushed(app, event)
            app.audioRecorder.stopRecording();
            app.StopButton_Track1.Visible = 'off';
            app.RecordButton_Track1.Visible = 'on';
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if ~app.isRecording   
                app.isPlaying = true;
                if ~app.audioPlayer.play(app.Axes_Track1, app.Axes_Track2)
                    app.EmptytracksLabel.Visible = 1;
                end
                    
                app.isPlaying = false;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~app.isRecording
                if app.isPlaying
                    app.audioPlayer.stop();
                end
            end
            
        end

        % Button pushed function: RecordButton_Track2
        function RecordButton_Track2Pushed(app, event)
            app.EmptytracksLabel.Visible = 0;           
            app.RecordingLamp_Track2.Color = [1, 0, 0];
            app.RecordButton_Track2.Visible = 'off';
            app.StopButton_Track2.Visible = 'on';
            app.audioRecorder.record(app.Axes_Track2, 2);
            app.RecordingLamp_Track2.Color = [0, 1, 0];
            app.audioPlayer.updateTracks(app.audioRecorder.getTrack2Audio(), 2);
        end

        % Button pushed function: StopButton_Track2
        function StopButton_Track2Pushed(app, event)
            app.audioRecorder.stopRecording();
            app.StopButton_Track2.Visible = 'off';
            app.RecordButton_Track2.Visible = 'on';
        end

        % Value changed function: VolumeKnob_Track1
        function VolumeKnob_Track1ValueChanged(app, event)
            value = app.VolumeKnob_Track1.Value;
            app.audioPlayer.changeVolumeOfTrack((value/6), 1)
        end

        % Value changed function: VolumeKnob_Track2
        function VolumeKnob_Track2ValueChanged(app, event)
            value = app.VolumeKnob_Track2.Value;
            app.audioPlayer.changeVolumeOfTrack((value/6), 2)
        end

        % Button pushed function: ConverttoMIDIButton_Track1
        function ConverttoMIDIButton_Track1Pushed(app, event)
            app.midiHandler.convertAudioToMidi(app.audioRecorder.getTrack1Audio(), 1);
            midiMessages = app.midiHandler.Track1Midi;
            app.audioPlayer.updateTracks(app.synthesizer.createAudio(midiMessages), 1);
        end

        % Button pushed function: CleanButton_Track1
        function CleanButton_Track1Pushed(app, event)
            
        end

        % Button pushed function: CleanButton_Track2
        function CleanButton_Track2Pushed(app, event)
            
        end

        % Button pushed function: ConverttoMIDIButton_Track2
        function ConverttoMIDIButton_Track2Pushed(app, event)
            app.midiHandler.convertAudioToMidi(app.audioRecorder.getTrack2Audio(), 2);
            midiMessages = app.midiHandler.Track2Midi;
            app.audioPlayer.updateTracks(app.synthesizer.createAudio(midiMessages), 2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RecordButton_Track2 = uibutton(app.UIFigure, 'push');
            app.RecordButton_Track2.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_Track2Pushed, true);
            app.RecordButton_Track2.Text = 'Record';
            app.RecordButton_Track1 = uibutton(app.UIFigure, 'push');
            app.RecordButton_Track1.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_Track1Pushed, true);
            app.RecordButton_Track1.Text = 'Record';
            app.VolumeKnobLabel = uilabel(app.UIFigure);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob_Track2 = uiknob(app.UIFigure, 'continuous');
            app.VolumeKnob_Track2.Limits = [1 12];
            app.VolumeKnob_Track2.MajorTicks = [1 6.5 12];
            app.VolumeKnob_Track2.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_Track2ValueChanged, true);
            app.VolumeKnob_Track2.Value = 6.5;
            app.VolumeKnob_2Label = uilabel(app.UIFigure);
            app.VolumeKnob_2Label.Text = 'Volume';
            app.VolumeKnob_Track1 = uiknob(app.UIFigure, 'continuous');
            app.VolumeKnob_Track1.Limits = [1 12];
            app.VolumeKnob_Track1.MajorTicks = [1 6.5 12];
            app.VolumeKnob_Track1.ValueChangedFcn = createCallbackFcn(app, @VolumeKnob_Track1ValueChanged, true);
            app.VolumeKnob_Track1.Value = 6.5;
            app.RecordingLampLabel = uilabel(app.UIFigure);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp_Track1 = uilamp(app.UIFigure);
            app.RecordingLamp_2Label = uilabel(app.UIFigure);
            app.RecordingLamp_2Label.Text = 'Recording';
            app.RecordingLamp_Track2 = uilamp(app.UIFigure);
            app.ChooseinstrumentDropDownLabel = uilabel(app.UIFigure);
            app.ChooseinstrumentDropDownLabel.Text = 'Choose instrument';
            app.ChooseinstrumentDropDown = uidropdown(app.UIFigure);
            app.ChooseinstrumentDropDown.Items = {'Synth', 'Option 2', 'Option 3', 'Option 4'};
            app.ChooseinstrumentDropDown.Value = 'Synth';
            app.ChooseinstrumentDropDown_2Label = uilabel(app.UIFigure);
            app.ChooseinstrumentDropDown_2Label.Text = 'Choose instrument';
            app.ChooseinstrumentDropDown_2 = uidropdown(app.UIFigure);
            app.ChooseinstrumentDropDown_2.Items = {'Synth', 'Option 2', 'Option 3', 'Option 4'};
            app.ChooseinstrumentDropDown_2.Value = 'Synth';
            app.MUSICNOTEPADLabel = uilabel(app.UIFigure);
            app.MUSICNOTEPADLabel.Text = 'MUSIC NOTEPAD';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CleanButton_Track1 = uibutton(app.UIFigure, 'push');
            app.CleanButton_Track1.ButtonPushedFcn = createCallbackFcn(app, @CleanButton_Track1Pushed, true);
            app.CleanButton_Track1.Text = 'Clean';
            app.CleanButton_Track2 = uibutton(app.UIFigure, 'push');
            app.CleanButton_Track2.ButtonPushedFcn = createCallbackFcn(app, @CleanButton_Track2Pushed, true);
            app.CleanButton_Track2.Text = 'Clean';
            app.ConverttoMIDIButton_Track2 = uibutton(app.UIFigure, 'push');
            app.ConverttoMIDIButton_Track2.ButtonPushedFcn = createCallbackFcn(app, @ConverttoMIDIButton_Track2Pushed, true);
            app.ConverttoMIDIButton_Track2.Text = 'Convert to MIDI';
            app.ConverttoMIDIButton_Track1 = uibutton(app.UIFigure, 'push');
            app.ConverttoMIDIButton_Track1.ButtonPushedFcn = createCallbackFcn(app, @ConverttoMIDIButton_Track1Pushed, true);
            app.ConverttoMIDIButton_Track1.Text = 'Convert to MIDI';
            app.StopButton_Track1 = uibutton(app.UIFigure, 'push');
            app.StopButton_Track1.ButtonPushedFcn = createCallbackFcn(app, @StopButton_Track1Pushed, true);
            app.StopButton_Track1.Visible = 'off';
            app.StopButton_Track1.Text = 'Stop';
            app.StopButton_Track2 = uibutton(app.UIFigure, 'push');
            app.StopButton_Track2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_Track2Pushed, true);
            app.StopButton_Track2.Visible = 'off';
            app.StopButton_Track2.Text = 'Stop';
            app.Panel_Track1 = uipanel(app.UIFigure);
            app.Panel_Track2 = uipanel(app.UIFigure);
            app.EmptytracksLabel = uilabel(app.UIFigure);
            app.EmptytracksLabel.Visible = 'off';
            app.EmptytracksLabel.Text = 'Empty tracks!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projekt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @MainWindowStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end